classdef ExperimentApp_V0_0 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        BIOMASSButtonGroup         matlab.ui.container.ButtonGroup
        YesButton_5                matlab.ui.control.ToggleButton
        NoButton_5                 matlab.ui.control.ToggleButton
        OrbitButtonGroup           matlab.ui.container.ButtonGroup
        LEOPolarButton_2           matlab.ui.control.ToggleButton
        LEO30Button                matlab.ui.control.ToggleButton
        LEO0Button                 matlab.ui.control.ToggleButton
        SSODDButton                matlab.ui.control.ToggleButton
        SSOAMButton                matlab.ui.control.ToggleButton
        SSOPMButton                matlab.ui.control.ToggleButton
        NotAssignedButton_2        matlab.ui.control.ToggleButton
        AltitudeButtonGroup        matlab.ui.container.ButtonGroup
        Button                     matlab.ui.control.ToggleButton
        Button_2                   matlab.ui.control.ToggleButton
        Button_3                   matlab.ui.control.ToggleButton
        Button_4                   matlab.ui.control.ToggleButton
        NotAssignedButton_3        matlab.ui.control.ToggleButton
        EvaluateDesignButton       matlab.ui.control.Button
        VIIRSButtonGroup           matlab.ui.container.ButtonGroup
        YesButton                  matlab.ui.control.ToggleButton
        NoButton                   matlab.ui.control.ToggleButton
        CMISButtonGroup            matlab.ui.container.ButtonGroup
        YesButton_2                matlab.ui.control.ToggleButton
        NoButton_2                 matlab.ui.control.ToggleButton
        SMAPRadiometerButtonGroup  matlab.ui.container.ButtonGroup
        YesButton_4                matlab.ui.control.ToggleButton
        NoButton_4                 matlab.ui.control.ToggleButton
        SMAPRadarButtonGroup       matlab.ui.container.ButtonGroup
        YesButton_3                matlab.ui.control.ToggleButton
        NoButton_3                 matlab.ui.control.ToggleButton
        InformationSheet           matlab.ui.container.Panel
        IAgreeButton               matlab.ui.control.Button
        HTML                       matlab.ui.control.HTML
        IDisagreeButton            matlab.ui.control.Button
        ExperimentChangePanel      matlab.ui.container.Panel
        ContinueButton             matlab.ui.control.Button
        HTML2                      matlab.ui.control.HTML
        UploadFilePanel            matlab.ui.container.Panel
        HTML3                      matlab.ui.control.HTML
        UploadResultsButton        matlab.ui.control.Button
        TutorialPanel              matlab.ui.container.Panel
        HTML3_2                    matlab.ui.control.HTML
        StartExperimentButton      matlab.ui.control.Button
        UIAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Consent % tracker for consent being accepted
        Order % What is the order of the experiments
        Experiment %What experiment is currently happening
        
        OrbitSelection % What is the current Orbit Selection
        ElevationSelection % What is the current selected elevation
        VIIRSSelection % What is the currect selected VIIRS options
        CMISSelection % What is the current CMIS selection
        BIOMASSSelection % What is the current BIOMASS selection
        RadiometerSelection % What is teh current radiometer selection
        RadarSelection % What is the current radar selection
        
        SelectionLog % List of all selections
        ScoreLog % list of all the scored architectures
        Scores % list of all selected scores
        
        ScoreMatrix % The score matrix
        
        myTimer % experiment timer
        experimentTime % Experiment length
        
        experimentResults % cell array to hold results
    end
    
    methods (Access = private)
        
        function LogSelection(app)
            % Add selection to log
            app.SelectionLog=[app.SelectionLog;app.OrbitSelection,app.ElevationSelection,...
                app.VIIRSSelection,app.CMISSelection,app.BIOMASSSelection,...
                app.RadiometerSelection,app.RadarSelection];
            
            % Update the score if autoevaluation is happening
            if strcmp(app.Experiment,"Auto Evaulate")
                app.ScoreLog=[app.ScoreLog;{[app.OrbitSelection,app.ElevationSelection,...
                    app.VIIRSSelection,app.CMISSelection,app.BIOMASSSelection,...
                    app.RadiometerSelection,app.RadarSelection],...
                    scoreEdsmSdsm(app)}];
                
                app.Scores=[app.Scores;scoreEdsmSdsm(app)];
                
                % Update Plot
                UpdatePlot(app);
            end
        end
        
        
        function SelectionScore = scoreEdsmSdsm(app)
            % Determine if Elevation and Orbit have been selected
            if strcmp(app.OrbitSelection,'Not Assigned')||strcmp(app.ElevationSelection,'Not Assigned')
                SelectionScore=[0,0];
                return
            else
                % Put together string of appropriate thingy
                % Orbit and inclination
                if strcmp(app.OrbitSelection,"LEO-Polar")
                    orbit="LEO-";
                    inclination="-polar-NA [";
                elseif strcmp(app.OrbitSelection,"LEO-30")
                    orbit="LEO-";
                    inclination="-30-NA [";
                elseif strcmp(app.OrbitSelection,"LEO-0")
                    orbit="LEO-";
                    inclination="-0-NA [";
                elseif strcmp(app.OrbitSelection,"SSO-DD")
                    orbit="SSO-";
                    inclination="-SSO-DD [";
                elseif strcmp(app.OrbitSelection,"SSO-AM")
                    orbit="SSO-";
                    inclination="-SSO-AM [";
                elseif strcmp(app.OrbitSelection,"SSO-PM")
                    orbit="SSO-";
                    inclination="-SSO-PM [";
                end
                
                % Combine with Altitude to get first half of string
                selection=strcat(orbit,app.ElevationSelection,inclination);
                
                % Add Selections
                if strcmp(app.VIIRSSelection,"Yes")
                    selection=strcat(selection,"VIIRS");
                    if strcmp(app.CMISSelection,"No")&&strcmp(app.RadiometerSelection,"No")&&strcmp(app.RadarSelection,"No")&&strcmp(app.BIOMASSSelection,"No")
                        selection=strcat(selection,"]");
                    else
                        selection=strcat(selection,", ");
                    end
                end
                if strcmp(app.CMISSelection,"Yes")
                    selection=strcat(selection,"CMIS");
                    if strcmp(app.RadiometerSelection,"No")&&strcmp(app.RadarSelection,"No")&&strcmp(app.BIOMASSSelection,"No")
                        selection=strcat(selection,"]");
                    else
                        selection=strcat(selection,", ");
                    end
                end
                if strcmp(app.RadiometerSelection,"Yes")
                    selection=strcat(selection,"SMAP_RAD");
                    if strcmp(app.RadarSelection,"No")&&strcmp(app.BIOMASSSelection,"No")
                        selection=strcat(selection,"]");
                    else
                        selection=strcat(selection,", ");
                    end
                end
                if strcmp(app.RadarSelection,"Yes")
                    selection=strcat(selection,"SMAP_MWR");
                    if strcmp(app.BIOMASSSelection,"No")
                        selection=strcat(selection,"]");
                    else
                        selection=strcat(selection,", ");
                    end
                end
                if strcmp(app.BIOMASSSelection,"Yes")
                    selection=strcat(selection,"BIOMASS]");
                end
            end
            
            % Find the thing in the list
            index=find(contains(app.ScoreMatrix(:,1),selection),1);
            %SelectionScore=sumDSM(index,1);
            
            % Lookup Decision
            SelectionScore=[app.ScoreMatrix{index,2},app.ScoreMatrix{index,3}];
            
        end
        
        
        function UpdatePlot(app)
            %update scatter plot
            scatter(app.UIAxes,app.Scores(:,2),app.Scores(:,1));
            
            % Find Pareto front by removing all points that are both more
            % expensive and less science
            pareto=app.Scores;
            domIndex=[];
            for i=1:height(pareto)
                dominated=(pareto(:,1)>pareto(i,1)).*(pareto(:,2)<pareto(i,2));
                domIndex=[domIndex;find(dominated==1)];
            end
            % Delete dominated points
            pareto(unique(domIndex),:)=[];
            % Highlight pareto front in red
            hold(app.UIAxes,'on')
            scatter(app.UIAxes,pareto(:,2),pareto(:,1),'r','h','MarkerFaceColor','red')
            
            % Highlight newest point with different shape maybe?
            scatter(app.UIAxes,app.Scores(height(app.Scores),2),app.Scores(height(app.Scores),1),'g','p','MarkerFaceColor','green')
            hold(app.UIAxes,'off')
            
            % Add Data Tip with labels of Choices 
            
        end
        
        function ChangeExperiment(app)
            % Record results in a cell array
            experimentResults{1,1}=app.Experiment;
            experimentResults{1,2}=app.SelectionLog; % List of all selections
            experimentResults{1,3}=app.ScoreLog; % list of all the scored architectures
            experimentResults{1,4}=app.Scores; % list of all selected scores
            
            % Pull up the Change Experiment Panel 
            app.ExperimentChangePanel.Enable='on';
            app.ExperimentChangePanel.Visible=true;
            
            % Reset Buttons
            app.NoButton.Value=true;
            app.NoButton_2.Value=true;
            app.NoButton_3.Value=true;
            app.NoButton_4.Value=true;
            app.NoButton_5.Value=true;
            app.NotAssignedButton_2.Value=true;
            app.NotAssignedButton_3.Value=true;
            
            % Reset Selections
            app.OrbitSelection = app.OrbitButtonGroup.SelectedObject.Text;
            app.ElevationSelection = app.AltitudeButtonGroup.SelectedObject.Text;
            app.VIIRSSelection = "No";
            app.CMISSelection="No";
            app.BIOMASSSelection="No";
            app.RadiometerSelection="No";
            app.RadarSelection="No";
            
            
            % switch experiment
            if strcmp(app.Experiment,"Push Button Evaulate")
                % Record Order
                app.Order="Auto Evaluate First";
                app.Experiment="Auto Evaulate";
                % Turn off the button
                app.EvaluateDesignButton.Visible=false;
                app.EvaluateDesignButton.Enable=false;
            else
                % Record Order
                app.Order="Button Evaluate First";
                app.Experiment="Push Button Evaulate";
                % Turn on the button
                app.EvaluateDesignButton.Visible=true;
                app.EvaluateDesignButton.Enable=true;
            end
            
            % Reset Logs
            app.SelectionLog=[app.OrbitSelection,app.ElevationSelection,...
                app.VIIRSSelection,app.CMISSelection,app.BIOMASSSelection,...
                app.RadiometerSelection,app.RadarSelection];
            app.ScoreLog=[];
            app.Scores=[];
            
            % Clear Plot
            cla(app.UIAxes);

        end
        
        function EndExperiment(app)
            % Pull Up Close Panel
            
            % Record results in a cell array
            experimentResults{2,1}=app.Experiment;
            experimentResults{2,2}=app.SelectionLog; % List of all selections
            experimentResults{2,3}=app.ScoreLog; % list of all the scored architectures
            experimentResults{2,4}=app.Scores; % list of all selected scores
            
            % Save Results 
            save('YourExperimentResults.mat','experimentResults')
            
            % Pull up the Upload File Experiment Panel 
            app.UploadFilePanel.Enable='on';
            app.UploadFilePanel.Visible=true;
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Turn on the Information Sheet
            app.InformationSheet.Visible=true;
            app.InformationSheet.Enable='on';
            
            % Change Panel is Off
            app.ExperimentChangePanel.Enable='off';
            app.ExperimentChangePanel.Visible=false;
            
            % Upload File Off
            app.UploadFilePanel.Enable='off';
            app.UploadFilePanel.Visible=false;
            
            % Set Initial States
            app.OrbitSelection="Not Assigned";
            app.ElevationSelection="Not Assigned";
            app.VIIRSSelection="No";
            app.CMISSelection="No";
            app.BIOMASSSelection="No";
            app.RadiometerSelection="No";
            app.RadarSelection="No";
            
            % Initiate Log
            app.SelectionLog=[app.OrbitSelection,app.ElevationSelection,...
                app.VIIRSSelection,app.CMISSelection,app.BIOMASSSelection,...
                app.RadiometerSelection,app.RadarSelection];
            
            % Load the JSON File
            filename="MultipleInstrumentsPerOrbit.json";
            jsonData = jsondecode(fileread(filename));
            costDSM={jsonData.architectures.cost}';
            scienceDSM={jsonData.architectures.science}';
            choice={jsonData.architectures.name}';
            
            app.ScoreMatrix=[choice,costDSM,scienceDSM];
            
            % Experiment Timer
            app.experimentTime=0.5; % In Minutes
        end

        % Button pushed function: IAgreeButton
        function IAgreeButtonPushed(app, event)
            % Record Consent
            app.Consent='I Agree';
            % Close Sheet
            app.InformationSheet.Visible=false;
            
            % Randomly select Experiment Order
            if rand()>0.5
                % Record Order
                app.Order="Auto Evaluate First";
                app.Experiment="Auto Evaulate";
                % Turn off the button
                app.EvaluateDesignButton.Visible=false;
                app.EvaluateDesignButton.Enable=false;
            else
                % Record Order
                app.Order="Button Evaluate First";
                app.Experiment="Push Button Evaulate";
                % Turn on the button
                app.EvaluateDesignButton.Visible=true;
                app.EvaluateDesignButton.Enable=true;
            end
            
            % Experiment Timer
            app.myTimer=timer('StartDelay',app.experimentTime*60,"ExecutionMode","singleShot",'TasksToExecute',1);
            app.myTimer.TimerFcn=@(myTimerObj,thisEvent)ChangeExperiment(app);
            start(app.myTimer);
        end

        % Selection changed function: OrbitButtonGroup
        function OrbitButtonGroupSelectionChanged(app, event)
            selectedButton = app.OrbitButtonGroup.SelectedObject;
            
            % Record new Selection
            app.OrbitSelection=selectedButton.Text;
            
            % Update Log
            LogSelection(app)
        end

        % Selection changed function: AltitudeButtonGroup
        function AltitudeButtonGroupSelectionChanged(app, event)
            selectedButton = app.AltitudeButtonGroup.SelectedObject;
            
            % Record new Selection
            app.ElevationSelection=selectedButton.Text;
            
            % Update Log
            LogSelection(app)
        end

        % Selection changed function: VIIRSButtonGroup
        function VIIRSButtonGroupSelectionChanged(app, event)
            selectedButton = app.VIIRSButtonGroup.SelectedObject;
            
            if strcmp(selectedButton.Text,'Yes')
                app.VIIRSSelection="Yes";
            else
                app.VIIRSSelection="No";
            end
            %app.VIIRSSelection=selectedButton.Text;
            
            % Update Log
            LogSelection(app)
        end

        % Selection changed function: CMISButtonGroup
        function CMISButtonGroupSelectionChanged(app, event)
            selectedButton = app.CMISButtonGroup.SelectedObject;
            
            app.CMISSelection=selectedButton.Text;
            
            % Update Log
            LogSelection(app)
        end

        % Selection changed function: BIOMASSButtonGroup
        function BIOMASSButtonGroupSelectionChanged(app, event)
            selectedButton = app.BIOMASSButtonGroup.SelectedObject;
            
            app.BIOMASSSelection=selectedButton.Text;
            
            % Update Log
            LogSelection(app)
        end

        % Selection changed function: SMAPRadiometerButtonGroup
        function SMAPRadiometerButtonGroupSelectionChanged(app, event)
            selectedButton = app.SMAPRadiometerButtonGroup.SelectedObject;
            
            app.RadiometerSelection=selectedButton.Text;
            
            % Update Log
            LogSelection(app)
        end

        % Selection changed function: SMAPRadarButtonGroup
        function SMAPRadarButtonGroupSelectionChanged(app, event)
            selectedButton = app.SMAPRadarButtonGroup.SelectedObject;
            
            app.RadarSelection=selectedButton.Text;
            
            % Update Log
            LogSelection(app)
        end

        % Button pushed function: EvaluateDesignButton
        function EvaluateDesignButtonPushed(app, event)
            % Evalute the design
            app.ScoreLog=[app.ScoreLog;{[app.OrbitSelection,app.ElevationSelection,...
                app.VIIRSSelection,app.CMISSelection,app.BIOMASSSelection,...
                app.RadiometerSelection,app.RadarSelection],...
                scoreEdsmSdsm(app)}];
            
            app.Scores=[app.Scores;scoreEdsmSdsm(app)];
            
            % Update Plot
            UpdatePlot(app);
        end

        % Button pushed function: ContinueButton
        function ContinueButtonPushed(app, event)
            % Start Experiment Session 2 
            % Start New Timer
            app.myTimer=timer('StartDelay',app.experimentTime*60,"ExecutionMode","singleShot",'TasksToExecute',1);
            app.myTimer.TimerFcn=@(myTimerObj,thisEvent)EndExperiment(app);
            start(app.myTimer);
            
            % Hide and Disable Change Panel 
            app.ExperimentChangePanel.Enable='off';
            app.ExperimentChangePanel.Visible=false;
        end

        % Button pushed function: UploadResultsButton
        function UploadResultsButtonPushed(app, event)
            % Open the Google Form 
            web('https://docs.google.com/forms/d/e/1FAIpQLSdIeGeL20xUDQeDWntKM-NWUN6gQxGL0pCMrf0W_2udFLtWxA/viewform?usp=sf_link');
            web('https://www.file.io/')
                        
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.BIOMASSButtonGroup = uibuttongroup(app.UIFigure);
            app.BIOMASSButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @BIOMASSButtonGroupSelectionChanged, true);
            app.BIOMASSButtonGroup.Title = 'BIOMASS';
            app.YesButton_5 = uitogglebutton(app.BIOMASSButtonGroup);
            app.YesButton_5.Text = 'Yes';
            app.NoButton_5 = uitogglebutton(app.BIOMASSButtonGroup);
            app.NoButton_5.Text = 'No';
            app.NoButton_5.Value = true;
            app.OrbitButtonGroup = uibuttongroup(app.UIFigure);
            app.OrbitButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @OrbitButtonGroupSelectionChanged, true);
            app.OrbitButtonGroup.Title = 'Orbit';
            app.LEOPolarButton_2 = uitogglebutton(app.OrbitButtonGroup);
            app.LEOPolarButton_2.Text = 'LEO-Polar';
            app.LEO30Button = uitogglebutton(app.OrbitButtonGroup);
            app.LEO30Button.Text = 'LEO-30';
            app.LEO0Button = uitogglebutton(app.OrbitButtonGroup);
            app.LEO0Button.Text = 'LEO-0';
            app.SSODDButton = uitogglebutton(app.OrbitButtonGroup);
            app.SSODDButton.Text = 'SSO-DD';
            app.SSOAMButton = uitogglebutton(app.OrbitButtonGroup);
            app.SSOAMButton.Text = 'SSO-AM';
            app.SSOPMButton = uitogglebutton(app.OrbitButtonGroup);
            app.SSOPMButton.Text = 'SSO-PM';
            app.NotAssignedButton_2 = uitogglebutton(app.OrbitButtonGroup);
            app.NotAssignedButton_2.Text = 'Not Assigned';
            app.NotAssignedButton_2.Value = true;
            app.AltitudeButtonGroup = uibuttongroup(app.UIFigure);
            app.AltitudeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @AltitudeButtonGroupSelectionChanged, true);
            app.AltitudeButtonGroup.Title = 'Altitude';
            app.Button = uitogglebutton(app.AltitudeButtonGroup);
            app.Button.Text = '400';
            app.Button_2 = uitogglebutton(app.AltitudeButtonGroup);
            app.Button_2.Text = '500';
            app.Button_3 = uitogglebutton(app.AltitudeButtonGroup);
            app.Button_3.Text = '600';
            app.Button_4 = uitogglebutton(app.AltitudeButtonGroup);
            app.Button_4.Text = '700';
            app.NotAssignedButton_3 = uitogglebutton(app.AltitudeButtonGroup);
            app.NotAssignedButton_3.Text = 'Not Assigned';
            app.NotAssignedButton_3.Value = true;
            app.EvaluateDesignButton = uibutton(app.UIFigure, 'push');
            app.EvaluateDesignButton.ButtonPushedFcn = createCallbackFcn(app, @EvaluateDesignButtonPushed, true);
            app.EvaluateDesignButton.Text = 'Evaluate Design';
            app.VIIRSButtonGroup = uibuttongroup(app.UIFigure);
            app.VIIRSButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @VIIRSButtonGroupSelectionChanged, true);
            app.VIIRSButtonGroup.Title = 'VIIRS';
            app.YesButton = uitogglebutton(app.VIIRSButtonGroup);
            app.YesButton.Text = 'Yes';
            app.NoButton = uitogglebutton(app.VIIRSButtonGroup);
            app.NoButton.Text = 'No';
            app.NoButton.Value = true;
            app.CMISButtonGroup = uibuttongroup(app.UIFigure);
            app.CMISButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @CMISButtonGroupSelectionChanged, true);
            app.CMISButtonGroup.Title = 'CMIS';
            app.YesButton_2 = uitogglebutton(app.CMISButtonGroup);
            app.YesButton_2.Text = 'Yes';
            app.NoButton_2 = uitogglebutton(app.CMISButtonGroup);
            app.NoButton_2.Text = 'No';
            app.NoButton_2.Value = true;
            app.SMAPRadiometerButtonGroup = uibuttongroup(app.UIFigure);
            app.SMAPRadiometerButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SMAPRadiometerButtonGroupSelectionChanged, true);
            app.SMAPRadiometerButtonGroup.Title = 'SMAP Radiometer';
            app.YesButton_4 = uitogglebutton(app.SMAPRadiometerButtonGroup);
            app.YesButton_4.Text = 'Yes';
            app.NoButton_4 = uitogglebutton(app.SMAPRadiometerButtonGroup);
            app.NoButton_4.Text = 'No';
            app.NoButton_4.Value = true;
            app.SMAPRadarButtonGroup = uibuttongroup(app.UIFigure);
            app.SMAPRadarButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SMAPRadarButtonGroupSelectionChanged, true);
            app.SMAPRadarButtonGroup.Title = 'SMAP Radar';
            app.YesButton_3 = uitogglebutton(app.SMAPRadarButtonGroup);
            app.YesButton_3.Text = 'Yes';
            app.NoButton_3 = uitogglebutton(app.SMAPRadarButtonGroup);
            app.NoButton_3.Text = 'No';
            app.NoButton_3.Value = true;
            app.InformationSheet = uipanel(app.UIFigure);
            app.InformationSheet.Enable = 'off';
            app.InformationSheet.Title = 'Information Sheet';
            app.InformationSheet.Visible = 'off';
            app.IAgreeButton = uibutton(app.InformationSheet, 'push');
            app.IAgreeButton.ButtonPushedFcn = createCallbackFcn(app, @IAgreeButtonPushed, true);
            app.IAgreeButton.Text = 'I Agree';
            app.HTML = uihtml(app.InformationSheet);
            app.HTML.HTMLSource = 'Information Sheet 3.htm';
            app.IDisagreeButton = uibutton(app.InformationSheet, 'push');
            app.IDisagreeButton.Text = 'I Disagree';
            app.ExperimentChangePanel = uipanel(app.UIFigure);
            app.ExperimentChangePanel.Enable = 'off';
            app.ExperimentChangePanel.Title = 'Experiment Change';
            app.ExperimentChangePanel.Visible = 'off';
            app.ContinueButton = uibutton(app.ExperimentChangePanel, 'push');
            app.ContinueButton.ButtonPushedFcn = createCallbackFcn(app, @ContinueButtonPushed, true);
            app.ContinueButton.Text = 'Continue';
            app.HTML2 = uihtml(app.ExperimentChangePanel);
            app.HTML2.HTMLSource = 'Experiment Change Notice.html';
            app.UploadFilePanel = uipanel(app.UIFigure);
            app.UploadFilePanel.Title = 'Upload File';
            app.HTML3 = uihtml(app.UploadFilePanel);
            app.HTML3.HTMLSource = 'UploadDirections.htm';
            app.UploadResultsButton = uibutton(app.UploadFilePanel, 'push');
            app.UploadResultsButton.ButtonPushedFcn = createCallbackFcn(app, @UploadResultsButtonPushed, true);
            app.UploadResultsButton.Text = 'Upload Results';
            app.TutorialPanel = uipanel(app.UIFigure);
            app.TutorialPanel.Title = 'Tutorial';
            app.HTML3_2 = uihtml(app.TutorialPanel);
            app.StartExperimentButton = uibutton(app.TutorialPanel, 'push');
            app.StartExperimentButton.Text = 'Start Experiment';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Tradespace')
            xlabel(app.UIAxes, 'Science')
            ylabel(app.UIAxes, 'Cost')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [0 1];
            app.UIAxes.YLim = [0 2000];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ExperimentApp_V0_0

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end